classdef menu1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ValoresDefaultButton       matlab.ui.control.Button
        seguinte                   matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        MassaBEditField            matlab.ui.control.NumericEditField
        MassaBEditFieldLabel       matlab.ui.control.Label
        MassaAEditField            matlab.ui.control.NumericEditField
        MassaAEditFieldLabel       matlab.ui.control.Label
        estticoBChoEditField       matlab.ui.control.NumericEditField
        estticoBChoEditFieldLabel  matlab.ui.control.Label
        cinticoBChoEditField       matlab.ui.control.NumericEditField
        cinticoBChoEditFieldLabel  matlab.ui.control.Label
        EstticoABEditField         matlab.ui.control.NumericEditField
        EstticoABEditFieldLabel    matlab.ui.control.Label
        CinticoABEditField         matlab.ui.control.NumericEditField
        CinticoABEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        i=0 
    end
    
    properties (Access = public)
        MassaB
        MassaA
        cof_estAB
        cof_estBC
        cof_cinAB
        cof_cinBC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CinticoABEditField, EstticoABEditField, 
        % ...and 4 other components
        function cinticoABEditFieldValueChanged(app, event)
            app.cof_cinAB = app.CinticoABEditField.Value;
            app.MassaB=app.MassaBEditField.Value;
            app.MassaA=app.MassaAEditField.Value;
            app.cof_estAB=app.EstticoABEditField.Value;
            app.cof_estBC=app.estticoBChoEditField.Value;
            app.cof_cinBC=app.cinticoBChoEditField.Value;
            V=[app.cof_cinAB app.MassaB app.MassaA app.cof_estAB app.cof_estBC app.cof_cinBC];
            if all(V>0)
                app.seguinte.Visible="on";
            else 
                app.seguinte.Visible = "off";
            end
        end

        % Button pushed function: seguinte
        function seguinteButtonPushed(app, event)
            sandbox=atrito04_5;
            sandbox.UIFigure.Visible='off';
            sandbox.MassaB=app.MassaBEditField.Value;
            sandbox.MassaA=app.MassaAEditField.Value;
            sandbox.cof_estAB=app.EstticoABEditField.Value;
            sandbox.cof_estBC=app.estticoBChoEditField.Value;
            sandbox.cof_cinBC=app.cinticoBChoEditField.Value;
            sandbox.cof_cinAB=app.CinticoABEditField.Value;
            delete(app)
            menu2(sandbox)
            
        end

        % Button pushed function: ValoresDefaultButton
        function ValoresDefaultButtonPushed(app, event)
            sandbox=atrito04_5;
            sandbox.UIFigure.Visible='off';
            sandbox.MassaB=2;
            sandbox.MassaA=1;
            sandbox.cof_estAB=0.50;
            sandbox.cof_estBC=0.50;
            sandbox.cof_cinBC=0.45;
            sandbox.cof_cinAB=0.45;
            delete(app)
            menu2(sandbox)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CinticoABEditFieldLabel = uilabel(app.UIFigure);
            app.CinticoABEditFieldLabel.Text = 'μ Cinético A/B';
            app.CinticoABEditField = uieditfield(app.UIFigure, 'numeric');
            app.CinticoABEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.EstticoABEditFieldLabel = uilabel(app.UIFigure);
            app.EstticoABEditFieldLabel.Text = 'μ Estático A/B';
            app.EstticoABEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstticoABEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.cinticoBChoEditFieldLabel = uilabel(app.UIFigure);
            app.cinticoBChoEditFieldLabel.Text = 'μ cinético B/Chão';
            app.cinticoBChoEditField = uieditfield(app.UIFigure, 'numeric');
            app.cinticoBChoEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.estticoBChoEditFieldLabel = uilabel(app.UIFigure);
            app.estticoBChoEditFieldLabel.Text = 'μ estático B/Chão';
            app.estticoBChoEditField = uieditfield(app.UIFigure, 'numeric');
            app.estticoBChoEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.MassaAEditFieldLabel = uilabel(app.UIFigure);
            app.MassaAEditFieldLabel.Text = 'Massa A';
            app.MassaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassaAEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.MassaBEditFieldLabel = uilabel(app.UIFigure);
            app.MassaBEditFieldLabel.Text = 'Massa B';
            app.MassaBEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassaBEditField.ValueChangedFcn = createCallbackFcn(app, @cinticoABEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'imagem_app.png';
            app.seguinte = uibutton(app.UIFigure, 'push');
            app.seguinte.ButtonPushedFcn = createCallbackFcn(app, @seguinteButtonPushed, true);
            app.seguinte.Visible = 'off';
            app.seguinte.Text = 'Seguinte';
            app.ValoresDefaultButton = uibutton(app.UIFigure, 'push');
            app.ValoresDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ValoresDefaultButtonPushed, true);
            app.ValoresDefaultButton.Text = 'Valores Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end